classdef appMaquinaPVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        DadosPVI           matlab.ui.container.Panel
        ftyLabel           matlab.ui.control.Label
        eF                 matlab.ui.control.EditField
        aLabel             matlab.ui.control.Label
        eA                 matlab.ui.control.NumericEditField
        eB                 matlab.ui.control.NumericEditField
        EditField2Label_2  matlab.ui.control.Label
        eN                 matlab.ui.control.NumericEditField
        EditField2Label_3  matlab.ui.control.Label
        EditField2Label_4  matlab.ui.control.Label
        eY0                matlab.ui.control.NumericEditField
        bbMetodos          matlab.ui.container.ButtonGroup
        rbEuler            matlab.ui.control.RadioButton
        rbEulerM           matlab.ui.control.RadioButton
        rbRK2              matlab.ui.control.RadioButton
        rbRK4              matlab.ui.control.RadioButton
        rbODE45            matlab.ui.control.RadioButton
        rbOutro            matlab.ui.control.RadioButton
        rbTodos            matlab.ui.control.RadioButton
        bAtualizar         matlab.ui.control.Button
        RightPanel         matlab.ui.container.Panel
        UITable            matlab.ui.control.Table
        UIAxes             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms y(t)
            strF = app.eF.Value;
            f =@(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;   
            n = app.eN.Value;           
            y0 = app.eY0.Value;
            
            escolhabgMetodos=app.bbMetodos.SelectedObject;
            MetodoSelecionado=find([app.rbEuler, app.rbEulerM,app.rbRK2, app.rbRK4, app.rbODE45, app.rbOutro, app.rbTodos]==escolhabgMetodos);
            
            
            try
                yEuler = NEuler(f,a,b,n,y0);
                yRK2 = NRK2(f,a,b,n,y0);
                yRK4=NRK4(f, a, b, n, y0);
                yODE45=NODE45(f, a, b, n, y0);
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                
                g =@(t) eval(vectorize(char(sExata)));
                
                h = (b-a)/n;
                t = a:h:b;
                yExata = g(t);
                erroEuler = abs(yExata-yEuler);
                erroRK2 = abs(yExata-yRK2);
                erroRK4 = abs(yExata-yRK4);
                erroODE45=abs(yExata-yODE45);
                
                switch MetodoSelecionado
                    case 1
                        cla(app.UIAxes)
                        hold(app.UIAxes,"on")
                        plot(app.UIAxes,t,yExata,"-r*")
                        plot(app.UIAxes,t,yEuler,"-c+")
                        hold(app.UIAxes,"off")
                        legenda={'Exata', 'Euler'};
                        tabela = [t.',yExata.',yEuler.',erroEuler.'];
                        nomeColunas={'t','Exata','Euler','erroEuler'};
                    case 2
                        warndlg("Método não implementado!", "AVISO", "modal");
                    case 3
                        cla(app.UIAxes)
                        hold(app.UIAxes,"on")
                        plot(app.UIAxes,t,yExata,"-r*")
                        plot(app.UIAxes,t,yRK2,"-c+")
                        hold(app.UIAxes,"off")
                        legenda={'Exata', 'RK2'};
                        tabela = [t.',yExata.',yRK2.',erroRK2.'];
                        nomeColunas={'t','Exata','RK2','erroRK2'};
                    case 4
                        cla(app.UIAxes)
                        hold(app.UIAxes,"on")
                        plot(app.UIAxes,t,yExata,"-r*")
                        plot(app.UIAxes,t,yRK4,"-c+")
                        hold(app.UIAxes,"off")
                        legenda={'Exata', 'RK4'};
                        tabela = [t.',yExata.',yRK4.',erroRK4.'];
                        nomeColunas={'t','Exata','RK4','erroRK4'};
                    case 5
                        cla(app.UIAxes)
                        hold(app.UIAxes,"on")
                        plot(app.UIAxes,t,yExata,"-r*")
                        plot(app.UIAxes,t,yODE45,"-c+")
                        hold(app.UIAxes,"off")
                        legenda={'Exata', 'ODE45'};
                        tabela = [t.',yExata.',yODE45.',erroODE45.'];
                        nomeColunas={'t','Exata','ODE45','erroODE45'};
                    case 6
                        warndlg("Método não implementado!", "AVISO", "modal");
                    case 7
                        cla(app.UIAxes)
                        hold(app.UIAxes,"on")
                        plot(app.UIAxes,t,yExata,"-r*")
                        plot(app.UIAxes,t,yEuler,"-g*")
                        plot(app.UIAxes,t,yRK2,"-b+")
                        plot(app.UIAxes,t,yRK4,"-k+")
                        plot(app.UIAxes,t,yODE45,"-c+")
                        legenda={'Exata', 'Euler', 'RK2', 'RK4', 'ODE45'};
                        tabela = [t.',yExata.',yEuler.',yRK2.', yRK4.', yODE45.', erroEuler.',erroRK2.', erroRK4.', erroODE45.'];
                        nomeColunas={'t','Exata','Euler','RK2', 'RK4', 'ODE45', 'erroEuler', 'erroRK2', 'erroRK4', 'erroODE45'};
                end
                
                T = array2table(tabela);
                app.UITable.Data = T;
                app.UITable.ColumnName = nomeColunas;
                
                hold(app.UIAxes,"off")
                grid(app.UIAxes,"on")
                legend(app.UIAxes, legenda)
                
            catch ME
                errordlg("Ocorreu um erro","Erro","modal")
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {402, 402};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {261, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosPVI = uipanel(app.LeftPanel);
            app.DadosPVI.Title = 'Dados do PVI';
            app.ftyLabel = uilabel(app.DadosPVI);
            app.ftyLabel.Text = 'f(t,y) = ';
            app.eF = uieditfield(app.DadosPVI, 'text');
            app.eF.Value = '-2*t*y';
            app.aLabel = uilabel(app.DadosPVI);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.DadosPVI, 'numeric');
            app.eB = uieditfield(app.DadosPVI, 'numeric');
            app.eB.Value = 1.5;
            app.EditField2Label_2 = uilabel(app.DadosPVI);
            app.EditField2Label_2.Text = 'Edit Field2';
            app.eN = uieditfield(app.DadosPVI, 'numeric');
            app.eN.Value = 10;
            app.EditField2Label_3 = uilabel(app.DadosPVI);
            app.EditField2Label_3.Text = 'Edit Field2';
            app.EditField2Label_4 = uilabel(app.DadosPVI);
            app.EditField2Label_4.Text = 'Edit Field2';
            app.eY0 = uieditfield(app.DadosPVI, 'numeric');
            app.eY0.Value = 2;
            app.bbMetodos = uibuttongroup(app.LeftPanel);
            app.bbMetodos.Title = 'Métodos';
            app.rbEuler = uiradiobutton(app.bbMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bbMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bbMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bbMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbODE45 = uiradiobutton(app.bbMetodos);
            app.rbODE45.Text = 'ODE45';
            app.rbOutro = uiradiobutton(app.bbMetodos);
            app.rbOutro.Text = 'Outro';
            app.rbTodos = uiradiobutton(app.bbMetodos);
            app.rbTodos.Text = 'Todos os métodos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaPVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end