classdef appMaquinaPVI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        bAtualizar  matlab.ui.control.Button
        bbMetodos   matlab.ui.container.ButtonGroup
        rbTodos     matlab.ui.control.RadioButton
        rbOutro     matlab.ui.control.RadioButton
        rbODE45     matlab.ui.control.RadioButton
        rbRK4       matlab.ui.control.RadioButton
        rbRK2       matlab.ui.control.RadioButton
        rbEulerM    matlab.ui.control.RadioButton
        rbEuler     matlab.ui.control.RadioButton
        DadosPVI    matlab.ui.container.Panel
        eY0         matlab.ui.control.NumericEditField
        y0Label     matlab.ui.control.Label
        nLabel      matlab.ui.control.Label
        eN          matlab.ui.control.NumericEditField
        bLabel      matlab.ui.control.Label
        eB          matlab.ui.control.NumericEditField
        eA          matlab.ui.control.NumericEditField
        aLabel      matlab.ui.control.Label
        eF          matlab.ui.control.EditField
        ftyLabel    matlab.ui.control.Label
        RightPanel  matlab.ui.container.Panel
        UITable     matlab.ui.control.Table
        UIAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms y(t)
            strF = app.eF.Value;
            f =@(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;   
            n = app.eN.Value;           
            y0 = app.eY0.Value;
            
            escolhabgMetodos=app.bbMetodos.SelectedObject;
            MetodoSelecionado=find([app.rbEuler, app.rbEulerM,app.rbRK2, app.rbRK4, app.rbODE45, app.rbOutro, app.rbTodos]==escolhabgMetodos);
            
            try
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                g =@(t) eval(vectorize(char(sExata)));
                h = (b-a)/n;
                t = a:h:b;
                yExata = g(t);
                
                
                switch MetodoSelecionado
                    case 1  % Método Euler
                        yEuler = MEuler(f,a,b,n,y0);
                        erroEuler = abs(yExata-yEuler);
                        cla(app.UIAxes)
                        hold(app.UIAxes,"on")
                        plot(app.UIAxes,t,yExata,"-r*")
                        plot(app.UIAxes,t,yEuler,"-b+")
                        hold(app.UIAxes,"off")
                        legenda={'Exata', 'Euler'};
                        tabela = [t.',yExata.',yEuler.',erroEuler.'];
                        nomeColunas={'t','Exata','Euler','erroEuler'};  % completo
                    case 2  % Método Euler Melhorado
                        yEulerM = MEulerM(f,a,b,n,y0);
                        erroEulerM = abs(yExata-yEulerM);
                        cla(app.UIAxes)
                        hold(app.UIAxes,"on")
                        plot(app.UIAxes,t,yExata,"-r*")
                        plot(app.UIAxes,t,yEulerM,"-b+")
                        hold(app.UIAxes,"off")
                        legenda={'Exata', 'EulerM'};
                        tabela = [t.',yExata.',yEulerM.',erroEulerM.'];
                        nomeColunas={'t','ExataM','EulerM','erroEulerM'};
                    case 3  % Método RK2
                        yRK2 = MRK2(f,a,b,n,y0);
                        erroRK2 = abs(yExata-yRK2);
                        cla(app.UIAxes)
                        hold(app.UIAxes,"on")
                        plot(app.UIAxes,t,yExata,"-r*")
                        plot(app.UIAxes,t,yRK2,"-b+")
                        hold(app.UIAxes,"off")
                        legenda={'Exata', 'RK4'};
                        tabela = [t.',yExata.',yRK2.',erroRK2.'];
                        nomeColunas={'t','Exata','RK2','erroRK2'};
                    case 4  % Método RK4
                        yRK4 = MRK4(f,a,b,n,y0);
                        erroRK4 = abs(yExata-yRK4);
                        cla(app.UIAxes)
                        hold(app.UIAxes,"on")
                        plot(app.UIAxes,t,yExata,"-r*")
                        plot(app.UIAxes,t,yRK4,"-b+")
                        hold(app.UIAxes,"off")
                        legenda={'Exata', 'RK4'};
                        tabela = [t.',yExata.',yRK4.',erroRK4.'];
                        nomeColunas={'t','Exata','RK4','erroRK4'};
                    case 5  % Função ODE45 do Matlab
                        yODE45 = M_ODE45(f,a,b,n,y0);
                        erroODE45 = abs(yExata-yODE45);
                        cla(app.UIAxes)
                        hold(app.UIAxes,"on")
                        plot(app.UIAxes,t,yExata,"-r*")
                        plot(app.UIAxes,t,yODE45,"-b+")
                        hold(app.UIAxes,"off")
                        legenda={'Exata', 'ODE45'};
                        tabela = [t.',yExata.',yODE45.',erroODE45.'];
                        nomeColunas={'t','Exata','ODE45','erroODE45'};
                    case 6  % Método do Ponto Médio
                        yPM = MPM(f,a,b,n,y0);
                        erroPM = abs(yExata-yPM);
                        cla(app.UIAxes)
                        hold(app.UIAxes,"on")
                        plot(app.UIAxes,t,yExata,"-r*")
                        plot(app.UIAxes,t,yPM,"-b+")
                        hold(app.UIAxes,"off")
                        legenda={'Exata', 'Ponto Médio'};
                        tabela = [t.',yExata.',yPM.',erroPM.'];
                        nomeColunas={'t','Exata','Ponto Médio','erroPM'};
                    case 7  % Todos os métodos
                        
                        yEuler = MEuler(f,a,b,n,y0); % Euler
                        erroEuler = abs(yExata-yEuler);
                        
                        yEulerM = MEulerM(f,a,b,n,y0); % EulerM
                        erroEulerM = abs(yExata-yEulerM);
                        
                        yRK2 = MRK2(f,a,b,n,y0); % RK2
                        erroRK2 = abs(yExata-yRK2);
                        
                        yRK4 = MRK4(f,a,b,n,y0); % Rk4
                        erroRK4 = abs(yExata-yRK4   );
                        
                        yODE45 = M_ODE45(f,a,b,n,y0); % ODE45
                        erroODE45 = abs(yExata-yODE45);
                        
                        yPM = MPM(f,a,b,n,y0); % PM
                        erroPM = abs(yExata-yPM);
                        cla(app.UIAxes)
                        hold(app.UIAxes,"on")
                        
                        plot(app.UIAxes,t,yEuler,"-g*")
                        plot(app.UIAxes,t,yEulerM,"-b*")
                        plot(app.UIAxes,t,yRK2,"-b*")
                        plot(app.UIAxes,t,yRK4,"-r*")
                        plot(app.UIAxes,t,yODE45,"-y*")
                        plot(app.UIAxes,t,yPM,"-b*")
                        
                        legenda={'Exata', 'Euler', 'EulerM', 'RK2', 'RK4', 'ODE45', 'PM'};
                        tabela = [t.',yExata.',yEuler.',erroEuler.',yEulerM.',erroEulerM.',yRK2.',erroRK2.',yRK4.',erroRK4.',yODE45.',erroODE45.',yPM.',erroPM.'];
                        nomeColunas={'t', 'Exata', 'Euler', 'erroEuler', 'EulerM', 'erroEulerM', 'RK2', 'erroRK2', 'RK4', 'erroRK4', 'ODE45', 'erroODE45', 'PM', 'erroPM'};
                        hold(app.UIAxes,"off")
                end
                
                %tabela = [t.',yExata.',yEuler.',yRK2.',erroEuler.',erroRK2.'];
                T = array2table(tabela);
                app.UITable.Data = T;
                app.UITable.ColumnName = nomeColunas;
                
                %plot(app.UIAxes,t,yExata,"-ro")
                %hold(app.UIAxes,"on")
                %plot(app.UIAxes,t,yEuler,"-g*")
                %plot(app.UIAxes,t,yRK2,"-b+")
                hold(app.UIAxes,"off")
                grid(app.UIAxes,"on")
                legend(app.UIAxes, legenda)
                
            catch ME
                errordlg("Ocorreu um erro","Erro","modal")
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {406, 406};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {261, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosPVI = uipanel(app.LeftPanel);
            app.DadosPVI.Title = 'Dados do PVI';
            app.ftyLabel = uilabel(app.DadosPVI);
            app.ftyLabel.Text = 'f(t,y) = ';
            app.eF = uieditfield(app.DadosPVI, 'text');
            app.aLabel = uilabel(app.DadosPVI);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.DadosPVI, 'numeric');
            app.eB = uieditfield(app.DadosPVI, 'numeric');
            app.bLabel = uilabel(app.DadosPVI);
            app.bLabel.Text = 'b';
            app.eN = uieditfield(app.DadosPVI, 'numeric');
            app.nLabel = uilabel(app.DadosPVI);
            app.nLabel.Text = 'n';
            app.y0Label = uilabel(app.DadosPVI);
            app.y0Label.Text = 'y0';
            app.eY0 = uieditfield(app.DadosPVI, 'numeric');
            app.bbMetodos = uibuttongroup(app.LeftPanel);
            app.bbMetodos.Title = 'Métodos';
            app.rbEuler = uiradiobutton(app.bbMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bbMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bbMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bbMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbODE45 = uiradiobutton(app.bbMetodos);
            app.rbODE45.Text = 'ODE45';
            app.rbOutro = uiradiobutton(app.bbMetodos);
            app.rbOutro.Text = 'Outro';
            app.rbTodos = uiradiobutton(app.bbMetodos);
            app.rbTodos.Text = 'Todos os métodos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaPVI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end