classdef appPVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppPVIUIFigure       matlab.ui.Figure
        Menu1                matlab.ui.container.Menu
        ExportarMenu         matlab.ui.container.Menu
        TabelaparaexcelMenu  matlab.ui.container.Menu
        About                matlab.ui.container.Menu
        RelatorioMenu        matlab.ui.container.Menu
        AutoresMenu          matlab.ui.container.Menu
        Tabela               matlab.ui.control.Table
        Atualizar            matlab.ui.control.Button
        Metodos              matlab.ui.container.ButtonGroup
        Outro                matlab.ui.control.RadioButton
        Todos                matlab.ui.control.RadioButton
        ODE45                matlab.ui.control.RadioButton
        EulerM               matlab.ui.control.RadioButton
        RK4                  matlab.ui.control.RadioButton
        RK2                  matlab.ui.control.RadioButton
        Euler                matlab.ui.control.RadioButton
        Dados                matlab.ui.container.Panel
        y0                   matlab.ui.control.NumericEditField
        y0EditFieldLabel     matlab.ui.control.Label
        n                    matlab.ui.control.NumericEditField
        nEditFieldLabel      matlab.ui.control.Label
        b                    matlab.ui.control.NumericEditField
        bEditFieldLabel      matlab.ui.control.Label
        a                    matlab.ui.control.NumericEditField
        aEditFieldLabel      matlab.ui.control.Label
        f                    matlab.ui.control.EditField
        ftyLabel             matlab.ui.control.Label
        Grafico              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.f.Value='t+y';
            AtualizarButtonPushed(app);
            msgbox('Faca x, faca y!');
            ImagemTeste();
        end

        % Button pushed function: Atualizar
        function AtualizarButtonPushed(app, event)
            %ler inputs
            strF=app.f.Value;
            f=@(t,y)eval(vectorize(strF));
            a=app.a.Value;
            b=app.b.Value;
            n=app.n.Value;
            y0=app.y0.Value;
            
            sExata=dsolve(['Dy=', strF],...
                            ['y(',num2str(a),')=',num2str(y0)]);
             g=@(t) eval(vectorize(sExata));
             h=(b-a)/n;
             t=a:h:b;
             yExata=g(t);
            
            valor=app.Metodos.SelectedObject.Text;

            switch valor
                case 'Euler'
                    %calculos
                    yEuler=NEuler(f,a,b,n,y0);
                    erroEuler=abs(yExata-yEuler);

                    %graficos
                    plot(app.Grafico,t,yEuler)
                    hold(app.Grafico,'on')
                    plot(app.Grafico,t,yExata)
                    hold(app.Grafico,'off')
                    legend(app.Grafico,'Euler', 'Exata');
                    
                    %tabela
                    tabela=[t' yExata' yEuler' erroEuler'];
                    tabela=array2table(tabela);
                    app.Tabela.Data=tabela;
                    app.Tabela.ColumnName={'t', 'Exata', 'Euler', 'Erro Euler'};
                case 'RK2'
                    warndlg('Nao implementado!','AVISO','modal');
                case 'RK4'
                    warndlg('Nao implementado!','AVISO','modal');
                case 'EulerM'
                    warndlg('Nao implementado!','AVISO','modal');
                case 'ODE45'
                    warndlg('Nao implementado!','AVISO','modal');
                case 'Todos'
                    warndlg('Nao implementado!','AVISO','modal');
                    
            end
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            %winopen('teste.pdf'); %nao funciona em linux
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            ImagemTeste();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppPVIUIFigure = uifigure('Visible', 'off');
            app.AppPVIUIFigure.Name = 'AppPVI';
            app.Menu1 = uimenu(app.AppPVIUIFigure);
            app.Menu1.Text = 'Menu';
            app.ExportarMenu = uimenu(app.AppPVIUIFigure);
            app.ExportarMenu.Text = 'Exportar';
            app.TabelaparaexcelMenu = uimenu(app.ExportarMenu);
            app.TabelaparaexcelMenu.Text = 'Tabela para excel';
            app.About = uimenu(app.AppPVIUIFigure);
            app.About.Text = 'About';
            app.RelatorioMenu = uimenu(app.About);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatorio';
            app.AutoresMenu = uimenu(app.About);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.Grafico = uiaxes(app.AppPVIUIFigure);
            title(app.Grafico, 'Gráfico')
            xlabel(app.Grafico, 'X')
            ylabel(app.Grafico, 'Y')
            zlabel(app.Grafico, 'Z')
            app.Dados = uipanel(app.AppPVIUIFigure);
            app.Dados.Title = 'Dados';
            app.ftyLabel = uilabel(app.Dados);
            app.ftyLabel.Text = 'f(t,y) =';
            app.f = uieditfield(app.Dados, 'text');
            app.aEditFieldLabel = uilabel(app.Dados);
            app.aEditFieldLabel.Text = 'a';
            app.a = uieditfield(app.Dados, 'numeric');
            app.bEditFieldLabel = uilabel(app.Dados);
            app.bEditFieldLabel.Text = 'b';
            app.b = uieditfield(app.Dados, 'numeric');
            app.b.Value = 2;
            app.nEditFieldLabel = uilabel(app.Dados);
            app.nEditFieldLabel.Text = 'n';
            app.n = uieditfield(app.Dados, 'numeric');
            app.n.Value = 2;
            app.y0EditFieldLabel = uilabel(app.Dados);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0 = uieditfield(app.Dados, 'numeric');
            app.y0.Value = 1;
            app.Metodos = uibuttongroup(app.AppPVIUIFigure);
            app.Metodos.Title = 'Métodos';
            app.Euler = uiradiobutton(app.Metodos);
            app.Euler.Text = 'Euler';
            app.Euler.Value = true;
            app.RK2 = uiradiobutton(app.Metodos);
            app.RK2.Text = 'RK2';
            app.RK4 = uiradiobutton(app.Metodos);
            app.RK4.Text = 'RK4';
            app.EulerM = uiradiobutton(app.Metodos);
            app.EulerM.Text = 'EulerM';
            app.ODE45 = uiradiobutton(app.Metodos);
            app.ODE45.Text = 'ODE45';
            app.Todos = uiradiobutton(app.Metodos);
            app.Todos.Text = 'Todos';
            app.Outro = uiradiobutton(app.Metodos);
            app.Outro.Text = 'Outro';
            app.Atualizar = uibutton(app.AppPVIUIFigure, 'push');
            app.Atualizar.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButtonPushed, true);
            app.Atualizar.Text = 'Atualizar';
            app.Tabela = uitable(app.AppPVIUIFigure);
            app.Tabela.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Tabela.RowName = {};
            % Show the figure after all components are created
            app.AppPVIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appPVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppPVIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppPVIUIFigure)
        end
    end
end