classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        HarmonicaLabel       matlab.ui.control.Label
        PassoEditField       matlab.ui.control.NumericEditField
        PassoEditFieldLabel  matlab.ui.control.Label
        passoEditField       matlab.ui.control.NumericEditField
        passoEditFieldLabel  matlab.ui.control.Label
        AtualizarButton      matlab.ui.control.Button
        bEditField_2         matlab.ui.control.NumericEditField
        bEditField_2Label    matlab.ui.control.Label
        dEditField           matlab.ui.control.NumericEditField
        dEditFieldLabel      matlab.ui.control.Label
        cEditField           matlab.ui.control.NumericEditField
        cEditFieldLabel      matlab.ui.control.Label
        aEditField           matlab.ui.control.NumericEditField
        aEditFieldLabel      matlab.ui.control.Label
        DadosLabel           matlab.ui.control.Label
        intdoYLabel          matlab.ui.control.Label
        intdoxLabel          matlab.ui.control.Label
        ZfxyEditField        matlab.ui.control.EditField
        ZfxyEditFieldLabel   matlab.ui.control.Label
        DerivadaX            matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AtualizarButton
        function AtualizarButtonPushed(app, event)
           
            strF=app.ZfxyEditField.Value;
            f=@(x,y) eval(vectorize(strF));
            
            %eixo dos x
            a=app.aEditField.Value;
            b=app.bEditField_2.Value;
            p2=app.PassoEditField.Value;
            
            %eixo dos y
            c=app.cEditField.Value;
            d=app.dEditField.Value;

            %passo
            p=app.passoEditField.Value;


            [x,y] = meshgrid(a:p2:b,c:p:d);
            Z= f(x,y);
            surf(app.UIAxes,x,y,Z);
            contour(app.UIAxes2,x,y,Z);
            
            syms x y
            %verificar se funçao e harmonica ou nao
            if diff(f,x,2)+diff(f,y,2)==0
                app.HarmonicaLabel.Text="Harmonica";
                app.HarmonicaLabel.BackgroundColor='g';
            else
                app.HarmonicaLabel.Text="Nao harmonica";
                app.HarmonicaLabel.BackgroundColor='r';
            end

            %representar derivada
            dzdx=diff(f,x); %em ordem a x
            dzdx_latex=['$$' '\frac{dz}{dx}=' latex(dzdx) '$$'];
            cla(app.DerivadaX);
            text(app.DerivadaX,0.1,0.5,dzdx_latex,'Units','normalized','Interpreter','latex','FontSize',18);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Curvas de Nivel')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.DerivadaX = uiaxes(app.UIFigure);
            title(app.DerivadaX, 'Derivada')
            app.DerivadaX.XTick = [];
            app.DerivadaX.YTick = [];
            app.DerivadaX.ZTick = [];
            app.ZfxyEditFieldLabel = uilabel(app.UIFigure);
            app.ZfxyEditFieldLabel.Text = 'Z=f(x,y)=';
            app.ZfxyEditField = uieditfield(app.UIFigure, 'text');
            app.ZfxyEditField.Value = 'x+y';
            app.intdoxLabel = uilabel(app.UIFigure);
            app.intdoxLabel.Text = 'int.do x:';
            app.intdoYLabel = uilabel(app.UIFigure);
            app.intdoYLabel.Text = 'int.do Y';
            app.DadosLabel = uilabel(app.UIFigure);
            app.DadosLabel.Text = 'Dados';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a=';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Value = -2;
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c=';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField.Value = -2;
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd=';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditField.Value = 2;
            app.bEditField_2Label = uilabel(app.UIFigure);
            app.bEditField_2Label.Text = 'b=';
            app.bEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.bEditField_2.Value = 2;
            app.AtualizarButton = uibutton(app.UIFigure, 'push');
            app.AtualizarButton.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButtonPushed, true);
            app.AtualizarButton.Text = {'Atualizar'; ''};
            app.passoEditFieldLabel = uilabel(app.UIFigure);
            app.passoEditFieldLabel.Text = 'passo';
            app.passoEditField = uieditfield(app.UIFigure, 'numeric');
            app.passoEditField.Value = 1;
            app.PassoEditFieldLabel = uilabel(app.UIFigure);
            app.PassoEditFieldLabel.Text = 'Passo';
            app.PassoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PassoEditField.Value = 1;
            app.HarmonicaLabel = uilabel(app.UIFigure);
            app.HarmonicaLabel.Text = 'Harmonica';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end