classdef appMaquinaEDO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Menu               matlab.ui.container.Menu
        SairMenu           matlab.ui.container.Menu
        RelatorioMenu      matlab.ui.container.Menu
        Menu2              matlab.ui.container.Menu
        AutorMenu          matlab.ui.container.Menu
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        bReset             matlab.ui.control.Button
        bAtualizar         matlab.ui.control.Button
        bgMetodos          matlab.ui.container.ButtonGroup
        rbTodos            matlab.ui.control.RadioButton
        rbODE23            matlab.ui.control.RadioButton
        rbODE45            matlab.ui.control.RadioButton
        rbRK4              matlab.ui.control.RadioButton
        rbRK2              matlab.ui.control.RadioButton
        rbEulerM           matlab.ui.control.RadioButton
        rbEuler            matlab.ui.control.RadioButton
        Panel              matlab.ui.container.Panel
        EditField2Label_4  matlab.ui.control.Label
        eY0                matlab.ui.control.NumericEditField
        EditField2Label_3  matlab.ui.control.Label
        eN                 matlab.ui.control.NumericEditField
        EditField2Label_2  matlab.ui.control.Label
        eB                 matlab.ui.control.NumericEditField
        eA                 matlab.ui.control.NumericEditField
        aLabel             matlab.ui.control.Label
        eF                 matlab.ui.control.EditField
        ftyEditFieldLabel  matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        UITable            matlab.ui.control.Table
        UIAxes             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        function y = NODE23(~,f,a,b,n,y0)
            h = (b-a)/n;
            t = a:h:b;
            [~,y] = ode23(f,t,y0);
            y = y.';          
        end
        
        function y = NODE45(~,f,a,b,n,y0)
            h = (b-a)/n;
            t = a:h:b;
            [~,y] = ode45(f,t,y0);
            y = y.'; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app, [])
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
        syms y(t)
        strF = app.eF.Value;
        f = @(t,y) eval(vectorize(strF));
        a = app.eA.Value;
        b = app.eB.Value;        
        n = app.eN.Value;
        y0 = app.eY0.Value;
        
        escolhabg = app.bgMetodos.SelectedObject;
        escolhaM = find([app.rbEuler, app.rbEulerM, app.rbRK2,...
                         app.rbRK4, app.rbODE45, app.rbODE23,...
                         app.rbTodos]==escolhabg);
        erroFuncao = MException("MATLAB:bAtualizar:BadF",...
                                "Introduza uma função em t e y");
        erroB = MException("MATLAB:bAtualizar:BadB",...
                           "Introduza um real maior que a");


        % outras 
        
        
        try
            
            try
                z = f(t,y);
                app.eF.BackgroundColor = "w";  
            catch
                app.eF.BackgroundColor = [1,0,0]; % [r,g,b]
                throw(erroFuncao)          
            end
            
            if ~(isreal(b) && b>a)
                app.eB.BackgroundColor = [1,0,0]; % [r,g,b]
                throw(erroB)   
            else
                 app.eB.BackgroundColor = [1,1,1]; % [r,g,b]               
            end
            
            
            yEuler = NEuler(f,a,b,n,y0);
            yRK2 = NRK2(f,a,b,n,y0);
            yODE23 = NODE23(app,f,a,b,n,y0);
            yODE45 = NODE45(app,f,a,b,n,y0);            
            
            sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
            
            g =@(t) eval(vectorize(char(sExata)));
            
            h = (b-a)/n;
            t = a:h:b;
            yExata = g(t);
            erroEuler = abs(yExata-yEuler);
            erroRK2 = abs(yExata-yRK2);
            erroODE23 = abs(yExata-yODE23);
            erroODE45 = abs(yExata-yODE45);

            plot(app.UIAxes,t,yExata,"-ro")
            hold(app.UIAxes,"on")            

            switch escolhaM
                case 1
                    plot(app.UIAxes,t,yEuler,"-g*")   
                    legenda = {'Exata','Euler'};
                    tabela = [t.',yExata.',yEuler.',erroEuler.']; 
                    nomeColunas = {'t','Exata','Euler','erroEuler'};
                case 2
                    warndlg("Método não implementado","Aviso","modal")
                case 3
                    plot(app.UIAxes,t,yRK2,"-bo")   
                    legenda = {'Exata','RK2'};
                    tabela = [t.',yExata.',yRK2.',erroRK2.']; 
                    nomeColunas = {'t','Exata','RK2','erroRK2'};                    
                case 4
                    warndlg("Método não implementado","Aviso","modal")                    
                case 5
                    plot(app.UIAxes,t,yODE45,"-kd")   
                    legenda = {'Exata','ODE45'};
                    tabela = [t.',yExata.',yODE45.',erroODE45.']; 
                    nomeColunas = {'t','Exata','ODE45','erroODE45'};
                case 6
                    plot(app.UIAxes,t,yODE23,"-yd")   
                    legenda = {'Exata','ODE23'};
                    tabela = [t.',yExata.',yODE23.',erroODE23.']; 
                    nomeColunas = {'t','Exata','ODE23','erroODE23'};                      
                case 7
                   plot(app.UIAxes,t,yEuler,"-g*")   
                   plot(app.UIAxes,t,yRK2,"-bo")   
                   legenda = {'Exata','Euler','RK2'};                  
                   tabela = [t.',yExata.',yEuler.',yRK2.',...
                             erroEuler.',erroRK2.']; 
                   nomeColunas = {'t','Exata','Euler','RK2',...
                                  'erroEuler','erroRK2'}; 
            end
            
            legend(app.UIAxes, legenda) 
            hold(app.UIAxes,"off")
            grid(app.UIAxes,"on")
            
            T = array2table(tabela);
            app.UITable.Data = T; 
            app.UITable.ColumnName = nomeColunas;
                
        catch ME
            errordlg(ME.message,"ERRO","modal")               
        end


        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Menu selected function: AutorMenu
        function AutorMenuSelected(app, event)
            autor
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            open("diarioasBeiras_HortaEcoCampus1pagina.pdf")
        end

        % Button pushed function: bReset
        function bResetButtonPushed(app, event)
            app.eF.Value='-2*t*y';
            app.eA.Value=0;
            app.eB.Value=1.5;
            app.eN.Value=10;
            app.eY0.Value=2;
            bAtualizarButtonPushed(app,[])

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {368, 368};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SairMenu = uimenu(app.Menu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.RelatorioMenu = uimenu(app.Menu);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatorio';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.AutorMenu = uimenu(app.Menu2);
            app.AutorMenu.MenuSelectedFcn = createCallbackFcn(app, @AutorMenuSelected, true);
            app.AutorMenu.Text = 'Autor';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = 'Panel';
            app.ftyEditFieldLabel = uilabel(app.Panel);
            app.ftyEditFieldLabel.Text = 'f(t,y) = ';
            app.eF = uieditfield(app.Panel, 'text');
            app.eF.Value = '-2*t*y';
            app.aLabel = uilabel(app.Panel);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 1.5;
            app.EditField2Label_2 = uilabel(app.Panel);
            app.EditField2Label_2.Text = 'Edit Field2';
            app.eN = uieditfield(app.Panel, 'numeric');
            app.eN.Value = 10;
            app.EditField2Label_3 = uilabel(app.Panel);
            app.EditField2Label_3.Text = 'Edit Field2';
            app.eY0 = uieditfield(app.Panel, 'numeric');
            app.eY0.Value = 2;
            app.EditField2Label_4 = uilabel(app.Panel);
            app.EditField2Label_4.Text = 'Edit Field2';
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = 'Métodos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbODE45 = uiradiobutton(app.bgMetodos);
            app.rbODE45.Text = 'ODE45';
            app.rbODE23 = uiradiobutton(app.bgMetodos);
            app.rbODE23.Text = 'ODE23';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.bReset = uibutton(app.LeftPanel, 'push');
            app.bReset.ButtonPushedFcn = createCallbackFcn(app, @bResetButtonPushed, true);
            app.bReset.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaEDO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end