classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MtodosNumricosEDOPVIUIFigure  matlab.ui.Figure
        ExportarMenu                  matlab.ui.container.Menu
        TabelaParaExcel               matlab.ui.container.Menu
        About                         matlab.ui.container.Menu
        RelatorioMenu                 matlab.ui.container.Menu
        AutoresMenu                   matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Reset                         matlab.ui.control.Button
        Atualizar                     matlab.ui.control.Button
        GrelhaGrafico                 matlab.ui.control.Switch
        GrelhadogrficoSwitchLabel     matlab.ui.control.Label
        Metodos                       matlab.ui.container.ButtonGroup
        Todos                         matlab.ui.control.RadioButton
        ODE45                         matlab.ui.control.RadioButton
        RK2                           matlab.ui.control.RadioButton
        PM                            matlab.ui.control.RadioButton
        EulerM                        matlab.ui.control.RadioButton
        RK4                           matlab.ui.control.RadioButton
        Euler                         matlab.ui.control.RadioButton
        Dados                         matlab.ui.container.Panel
        y0                            matlab.ui.control.NumericEditField
        n                             matlab.ui.control.NumericEditField
        b                             matlab.ui.control.NumericEditField
        a                             matlab.ui.control.NumericEditField
        y0EditFieldLabel              matlab.ui.control.Label
        nEditFieldLabel               matlab.ui.control.Label
        bEditFieldLabel               matlab.ui.control.Label
        aEditFieldLabel               matlab.ui.control.Label
        f                             matlab.ui.control.EditField
        ftyLabel                      matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        Tabela                        matlab.ui.control.Table
        Grafico                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function y = NODE45(~,f,a,b,n,y0)
            h = (b-a)/n;
            t = a:h:b;
            [~,y] = ode45(f,t,y0);
            y = y.'; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            AtualizarButtonPushed(app,[]);
        end

        % Button pushed function: Atualizar
        function AtualizarButtonPushed(app, event)
            %ler inputs
            syms y(t)

            strF=app.f.Value; 
            f=@(t,y)eval(vectorize(strF));
            a=app.a.Value;
            b=app.b.Value;
            n=app.n.Value;
            y0=app.y0.Value;

            erroFuncao=MException("MATLAB:bAtualizar:BadF", "Introduza uma função em t e y");
            erroA=MException("MATLAB:bAtualizar:BadA", "Introduza um numero real em a");
            erroB=MException("MATLAB:bAtualizar:BadB", "Introduza um numero real maior que a");
            erroN=MException("MATLAB:bAtualizar:BadN", "Introduza um inteiro maior que 0 em n");
            erroY0=MException("MATLAB:bAtualizar:Bady0", "Introduza um numero real em y0");

            try
                try %verifica se a função é em t e y
                    z = f(t,y);
                    app.f.BackgroundColor=[1,1,1]; % [r,g,b];  
                catch
                    app.f.BackgroundColor=[1,0,0]; % [r,g,b]
                    throw(erroFuncao)          
                end

                 if ~(isreal(a)) %verifica se a é real
                    app.a.BackgroundColor=[1,0,0]; % [r,g,b]
                    throw(erroA)   
                else
                     app.a.BackgroundColor=[1,1,1]; % [r,g,b]               
                end
                
                if ~(isreal(b) && b>a) %verifica se b é real e maior que a
                    app.b.BackgroundColor=[1,0,0]; % [r,g,b]
                    throw(erroB)   
                else
                     app.b.BackgroundColor=[1,1,1]; % [r,g,b]               
                end

                if ~(mod(n,1)==0 && n>0) %verifica se n é inteiro e maior que zero
                    app.n.BackgroundColor=[1,0,0]; % [r,g,b]
                    throw(erroN);
                else
                    app.n.BackgroundColor=[1,1,1]; % [r,g,b]
                end

                if ~(isreal(y0)) %verifica se y0 é real
                    app.y0.BackgroundColor=[1,0,0]; % [r,g,b]
                    throw(erroY0)   
                else
                     app.y0.BackgroundColor=[1,1,1]; % [r,g,b]               
                end

                sExata=dsolve(diff(y,t)==f(t,y),y(a)==y0);
                g=@(t) eval(vectorize(sExata));
                h=(b-a)/n;
                t=a:h:b;
                yExata=g(t);
                
                %Cálculo das aproximações
                yEuler = N_Euler(f,a,b,n,y0);
                yEulerM = N_EulerM(f,a,b,n,y0);
                yRK2 = N_RK2(f,a,b,n,y0);
                yRK4 = N_RK4(f,a,b,n,y0);
                yODE45 = NODE45(app,f,a,b,n,y0);
                yPM = N_PM(f,a,b,n,y0);
                
                %Cálculo dos erros
                erroEuler = abs(yExata-yEuler);
                erroEulerM = abs(yExata-yEulerM);
                erroRK2 = abs(yExata-yRK2);
                erroRK4 = abs(yExata-yRK4);
                erroODE45 = abs(yExata-yODE45);
                erroPM = abs(yExata-yPM);

                valor=app.Metodos.SelectedObject.Text;
                
                plot(app.Grafico,t,yExata,"-ro")
                hold(app.Grafico,"on")
    
                switch valor
                    case 'Euler'   
                        %grafico
                        plot(app.Grafico,t,yEuler,"-g*")
                        legenda = {'Exata','Euler'};
                        
                        %tabela
                        tabela=[t' yExata' yEuler' erroEuler'];
                        nomeColunas={'t', 'Exata', 'Euler', 'Erro Euler'};
    
                    case 'RK2'
                       %grafico
                        plot(app.Grafico,t,yRK2,"-b+")
                        legenda = {'Exata','RK2'};
                        
                        %tabela
                        tabela=[t' yExata' yRK2' erroRK2'];
                        nomeColunas={'t', 'Exata', 'RK2', 'Erro RK2'};
    
                    case 'RK4'
                        %grafico
                        plot(app.Grafico,t,yRK4,"-kd")
                        legenda = {'Exata','RK4'};
                        
                        %tabela
                        tabela=[t' yExata' yRK4' erroRK4'];
                        nomeColunas={'t', 'Exata', 'RK4', 'Erro RK4'};
    
                    case 'EulerM'
                        %grafico
                        plot(app.Grafico,t,yEulerM,"-yd")
                        legenda = {'Exata','EulerM'};
                        
                        %tabela
                        tabela=[t' yExata' yEulerM' erroEulerM'];
                        nomeColunas={'t', 'Exata', 'EulerM', 'Erro EulerM'};

                    case 'ODE45'
                        %grafico
                        plot(app.Grafico,t,yODE45,"-c+")   
                        legenda = {'Exata','ODE45'};

                        %tabela
                        tabela = [t.',yExata.',yODE45.',erroODE45.']; 
                        nomeColunas = {'t','Exata','ODE45','erroODE45'};
                    case 'PM'
                        %grafico
                        plot(app.Grafico,t,yPM,"-c+")   
                        legenda = {'Exata','PM'};

                        %tabela
                        tabela = [t.',yExata.',yPM.',erroPM.']; 
                        nomeColunas = {'t','Exata','PM','erroPM'};

                    case 'Todos'
                        %gráficos
                        plot(app.Grafico,t,yEuler,"-g*")
                        plot(app.Grafico,t,yEulerM,"-yd")
                        plot(app.Grafico,t,yRK2,"-b+")
                        plot(app.Grafico,t,yRK4,"-kd")
                        plot(app.Grafico,t,yODE45,"-c+")
                        plot(app.Grafico,t,yPM,"-m+")
                        legenda = {'Exata','Euler','EulerM','RK2','RK4','ODE45','PM'};

                        %tabela
                        tabela = [t.',yExata.',yEuler.',yEulerM.',yRK2.',yRK4.',yODE45.',yPM.',erroEuler.',erroEulerM.',erroRK2.',erroRK4.',erroODE45.',erroPM.']; 
                        nomeColunas = {'t','Exata','Euler','EulerM','RK2','RK4','ODE45','PM','erroEuler','erroEulerM','erroRK2','erroRK4','erroODE45','erroPM'};
                        
                end

                legend(app.Grafico, legenda) 
                hold(app.Grafico,"off")
                
                T = array2table(tabela);
                app.Tabela.Data = T; 
                app.Tabela.ColumnName = nomeColunas;

            catch ME
                errordlg(ME.message,"ERRO","modal")
            end
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            open('Relatorio.pdf');
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            Autores();
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.f.Value='-2*t*y';
            app.a.Value=0;
            app.b.Value=1.5;
            app.n.Value=10;
            app.y0.Value=2;
            app.Euler.Value=true;
            app.GrelhaGrafico.Value='Off';
            grid(app.Grafico,"off");
            AtualizarButtonPushed(app,[]);
        end

        % Value changed function: GrelhaGrafico
        function GrelhaGraficoValueChanged(app, event)
            value = strcmp(app.GrelhaGrafico.Value,'On');
            if(value)
                grid(app.Grafico,"on")
            else
                grid(app.Grafico,"off")
            end
        end

        % Menu selected function: TabelaParaExcel
        function TabelaParaExcelMenuSelected(app, event)
            try
                writetable(app.Tabela.Data,'data.xlsx','Sheet',1);
            catch
                waitfor(errordlg("Tabela vazia","Erro"))
            end
        end

        % Callback function
        function SairMenuSelected(app, event)
            SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.MtodosNumricosEDOPVIUIFigure);
            end
        end

        % Close request function: MtodosNumricosEDOPVIUIFigure
        function MtodosNumricosEDOPVIUIFigureCloseRequest(app, event)
            SairMenuSelected(app, []);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MtodosNumricosEDOPVIUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {516, 516};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {235, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MtodosNumricosEDOPVIUIFigure = uifigure('Visible', 'off');
            app.MtodosNumricosEDOPVIUIFigure.Name = 'Métodos Numéricos EDO/PVI';
            app.MtodosNumricosEDOPVIUIFigure.Icon = fullfile(pathToMLAPP, 'logo.png');
            app.MtodosNumricosEDOPVIUIFigure.CloseRequestFcn = createCallbackFcn(app, @MtodosNumricosEDOPVIUIFigureCloseRequest, true);
            app.MtodosNumricosEDOPVIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ExportarMenu = uimenu(app.MtodosNumricosEDOPVIUIFigure);
            app.ExportarMenu.Text = 'Exportar';
            app.TabelaParaExcel = uimenu(app.ExportarMenu);
            app.TabelaParaExcel.MenuSelectedFcn = createCallbackFcn(app, @TabelaParaExcelMenuSelected, true);
            app.TabelaParaExcel.Text = 'Tabela para excel';
            app.About = uimenu(app.MtodosNumricosEDOPVIUIFigure);
            app.About.Text = 'About';
            app.RelatorioMenu = uimenu(app.About);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatorio';
            app.AutoresMenu = uimenu(app.About);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.GridLayout = uigridlayout(app.MtodosNumricosEDOPVIUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Dados = uipanel(app.LeftPanel);
            app.Dados.Title = 'Dados';
            app.ftyLabel = uilabel(app.Dados);
            app.ftyLabel.Text = 'f(t,y) =';
            app.f = uieditfield(app.Dados, 'text');
            app.f.Value = '-2*t*y';
            app.aEditFieldLabel = uilabel(app.Dados);
            app.aEditFieldLabel.Text = 'a';
            app.bEditFieldLabel = uilabel(app.Dados);
            app.bEditFieldLabel.Text = 'b';
            app.nEditFieldLabel = uilabel(app.Dados);
            app.nEditFieldLabel.Text = 'n';
            app.y0EditFieldLabel = uilabel(app.Dados);
            app.y0EditFieldLabel.Text = 'y0';
            app.a = uieditfield(app.Dados, 'numeric');
            app.b = uieditfield(app.Dados, 'numeric');
            app.b.Value = 1.5;
            app.n = uieditfield(app.Dados, 'numeric');
            app.n.Value = 10;
            app.y0 = uieditfield(app.Dados, 'numeric');
            app.y0.Value = 2;
            app.Metodos = uibuttongroup(app.LeftPanel);
            app.Metodos.Title = 'Métodos';
            app.Euler = uiradiobutton(app.Metodos);
            app.Euler.Text = 'Euler';
            app.Euler.Value = true;
            app.RK4 = uiradiobutton(app.Metodos);
            app.RK4.Text = 'RK4';
            app.EulerM = uiradiobutton(app.Metodos);
            app.EulerM.Text = 'EulerM';
            app.PM = uiradiobutton(app.Metodos);
            app.PM.Text = 'PM';
            app.RK2 = uiradiobutton(app.Metodos);
            app.RK2.Text = 'RK2';
            app.ODE45 = uiradiobutton(app.Metodos);
            app.ODE45.Text = 'ODE45';
            app.Todos = uiradiobutton(app.Metodos);
            app.Todos.Text = 'Todos';
            app.GrelhadogrficoSwitchLabel = uilabel(app.LeftPanel);
            app.GrelhadogrficoSwitchLabel.Text = 'Grelha do gráfico';
            app.GrelhaGrafico = uiswitch(app.LeftPanel, 'slider');
            app.GrelhaGrafico.ValueChangedFcn = createCallbackFcn(app, @GrelhaGraficoValueChanged, true);
            app.Atualizar = uibutton(app.LeftPanel, 'push');
            app.Atualizar.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButtonPushed, true);
            app.Atualizar.Text = 'Atualizar';
            app.Reset = uibutton(app.LeftPanel, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.Grafico = uiaxes(app.RightPanel);
            title(app.Grafico, 'Gráfico')
            xlabel(app.Grafico, 'X')
            ylabel(app.Grafico, 'Y')
            zlabel(app.Grafico, 'Z')
            app.Tabela = uitable(app.RightPanel);
            app.Tabela.ColumnName = {''};
            app.Tabela.RowName = {};
            % Show the figure after all components are created
            app.MtodosNumricosEDOPVIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MtodosNumricosEDOPVIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MtodosNumricosEDOPVIUIFigure)
        end
    end
end