classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DerivaoeIntegracaoNumerica  matlab.ui.Figure
        ExportarMenu                matlab.ui.container.Menu
        TabelaparaexcelMenu         matlab.ui.container.Menu
        AboutMenu                   matlab.ui.container.Menu
        RelatrioMenu                matlab.ui.container.Menu
        AutoresMenu                 matlab.ui.container.Menu
        Tab                         matlab.ui.container.TabGroup
        FrVr                        matlab.ui.container.Tab
        Resultados                  matlab.ui.container.Panel
        GrelhaGrafico               matlab.ui.control.Switch
        GrelhadogrficoSwitchLabel   matlab.ui.control.Label
        ErroAproximcao              matlab.ui.control.Lamp
        ErrodaaproximaoLabel        matlab.ui.control.Label
        Tabela                      matlab.ui.control.Table
        Grafico                     matlab.ui.control.UIAxes
        DadosGeral                  matlab.ui.container.Panel
        Reset                       matlab.ui.control.Button
        FormulasDNumerica           matlab.ui.container.ButtonGroup
        DFR3                        matlab.ui.control.RadioButton
        D2                          matlab.ui.control.RadioButton
        DFP3                        matlab.ui.control.RadioButton
        DFC                         matlab.ui.control.RadioButton
        DFR                         matlab.ui.control.RadioButton
        DFP                         matlab.ui.control.RadioButton
        Atualizar                   matlab.ui.control.Button
        Trap                        matlab.ui.control.NumericEditField
        RTrapziosLabel              matlab.ui.control.Label
        Simp                        matlab.ui.control.NumericEditField
        RSimpsonsLabel              matlab.ui.control.Label
        Dados                       matlab.ui.container.Panel
        b                           matlab.ui.control.EditField
        aLabel_2                    matlab.ui.control.Label
        a                           matlab.ui.control.EditField
        aLabel                      matlab.ui.control.Label
        h                           matlab.ui.control.NumericEditField
        hEditFieldLabel             matlab.ui.control.Label
        f                           matlab.ui.control.EditField
        fxLabel                     matlab.ui.control.Label
        Mostrador                   matlab.ui.control.UIAxes
        Fr2Vr                       matlab.ui.container.Tab
        Resultados2                 matlab.ui.container.Panel
        Grafico2                    matlab.ui.control.UIAxes
        Dados2                      matlab.ui.container.Panel
        Reset2                      matlab.ui.control.Button
        DerivadaZY                  matlab.ui.control.Label
        DerivadaZX                  matlab.ui.control.Label
        Harmonica                   matlab.ui.control.EditField
        Atualizar2                  matlab.ui.control.Button
        DadosGeral2                 matlab.ui.container.Panel
        d                           matlab.ui.control.EditField
        aLabel_6                    matlab.ui.control.Label
        c                           matlab.ui.control.EditField
        aLabel_5                    matlab.ui.control.Label
        b2                          matlab.ui.control.EditField
        aLabel_4                    matlab.ui.control.Label
        a2                          matlab.ui.control.EditField
        aLabel_3                    matlab.ui.control.Label
        h3                          matlab.ui.control.NumericEditField
        hLabel_4                    matlab.ui.control.Label
        h2                          matlab.ui.control.NumericEditField
        hLabel                      matlab.ui.control.Label
        f2                          matlab.ui.control.EditField
        fxyLabel                    matlab.ui.control.Label
        Mostrador3                  matlab.ui.control.UIAxes
        Mostrador2                  matlab.ui.control.UIAxes
    end

    methods (Access = public)

        function [x,y,dydx] = NDerivadaDFP(~,f,a,b,h,y) %progressivas em 2 pontos
            x = a:h:b;
            n = length(x);
            if nargin == 5
                y = f(x);
            end
            dydx = zeros(1,n);
            for i = 1:n-1
                dydx(i) = (y(i+1)-y(i))/h;
            end
            dydx(n) = dydx(n-1);
        end

        function [x,y,dydx]=NDerivadaDFR(~,f,a,b,h,y) %regressivas
            x=a:h:b;
            n=length(x);

            if nargin==5
                y=f(x);
            end

            dydx=zeros(1,n);
            dydx(1)=(y(2)-y(1))/h;

            for i=2:n
                dydx(i)=(y(i)-y(i-1))/h;
            end
        end

        function [x,y,dydx]=NDerivadaF2D(~,f,a,b,h,y) %2 derivada
            x=a:h:b;
            n=length(x);

            if nargin==5
                y=f(x);
            end

            dydx=zeros(1,n);

            dydx(1)=(y(2)-y(1))/h;
            for i=2:n-1
                dydx(i)=(y(i+1)-2*y(i)+y(i-1))/(h*h);
            end
            dydx(n)=(y(n)-y(n-1))/h;
        end

        function [x,y,dydx]=NDerivadaFC(~,f,a,b,h,y) %centrada em 3 pontos
            x=a:h:b;
            n=length(x);

            if nargin==5
                y=f(x);
            end

            dydx=zeros(1,n);

            dydx(1)=(y(2)-y(1))/h;
            for i=2:n-1
                dydx(i)=(y(i+1)-y(i-1))/(2*h);
            end
            dydx(n)=(y(n)-y(n-1))/h;
        end

        function [x,y,dydx]=NDerivadaFPT(~,f,a,b,h,y) %progressiva 3 pontos
            x=a:h:b;
            n=length(x);

            if nargin==5
                y=f(x);
            end

            dydx=zeros(1,n);

            for i=1:n-2
                dydx(i)=(-3*y(i)+4*y(i+1)-y(i+2))/(2*h);
            end
            dydx(n-1)=(y(n-3)-4*y(n-2)+3*y(n-1))/(2*h);
            dydx(n)=(y(n-2)-4*y(n-1)+3*y(n))/(2*h);
        end

        function [x,y,dydx]=NDerivadaFRT(~,f,a,b,h,y) %regressiva 3 pontos
            x=a:h:b;
            n=length(x);

            if nargin==5
                y=f(x);
            end

            dydx=zeros(1,n);
            dydx(1)=(-3*y(1)+4*y(2)-y(3))/(2*h);
            dydx(2)=(-3*y(2)+4*y(3)-y(4))/(2*h);

            for i=3:n
                dydx(i)=(y(i-2)-4*y(i-1)+3*y(i))/(2*h);
            end
        end

        function S = RSimpson(~,f,a,b,n)
            h = (b-a)/n;
            x = a:h:b;
            s = 0;
            for i = 1:n-1
                if mod(i,2)==0
                    s = s+2*f(x(i));
                else
                    s = s+4*f(x(i));
                end
            end
            S = h/3*(f(a)+s+f(b));
        end

        function T = RTrapezios(~,f,a,b,n)
            h = (b-a)/n;
            x = a:h:b;
            s = 0;
            for i = 1:n-1
                s = s+f(x(i));
            end
            T = h/2*(f(a)+2*s+f(b));
        end

        function YN = isHarmonica(~,f) %verificar se a função é harmónica
            syms x y;
            if (diff(f,x,2)+diff(f,y,2)==0)
                YN = 1;
            else
                YN = 0;
            end
        end

        function mudaLamp(app, erro) %mudar lâmpada consoante o erro
            if(abs(erro(length(erro))-erro(length(erro)-1))<0.1)
                app.ErroAproximcao.Color='green';
            else
                app.ErroAproximcao.Color='red';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            AtualizarButtonPushed(app);
            Atualizar2ButtonPushed(app);
        end

        % Button pushed function: Atualizar
        function AtualizarButtonPushed(app, event)
            strF = app.f.Value;
            f = @(x) eval(vectorize(strF));
            a = str2num(app.a.Value);
            h = app.h.Value;
            b = str2num(app.b.Value);
            metodo = app.FormulasDNumerica.SelectedObject.Text;
            app.ErroAproximcao.Color='w';

            erroFuncaoF=MException('MATLAB:appMaquinaCDI:badF','Introduza uma função z em x');
            erroA=MException('MATLAB:appMaquinaCDI:BadA','Introduza um número real em a');
            erroB=MException("MATLAB:bAtualizar:BadB", "Introduza um numero real maior que a");
            erroH=MException('MATLAB:appMaquinaCDI:badH','Introduza um numero inteiro maior que 0 em h');

            try
                try
                    erroFuncaoF = f(sym('x'));
                    app.f.BackgroundColor = 'white';
                catch ME
                    app.f.BackgroundColor = 'red';
                    throw(erroFuncaoF)
                end

                if ~(isscalar(a) && isreal(a))
                    app.a.BackgroundColor = 'red';
                    throw(erroA)
                else
                    app.a.BackgroundColor = 'white';
                end

                if ~(isscalar(b) && isreal(b) && b>a)
                    app.b.BackgroundColor = 'red';
                    throw(erroB)
                else
                    app.b.BackgroundColor = 'white';
                end

                if ~(isscalar(h) && h>0)
                    app.h.BackgroundColor = 'red';
                    throw(erroH)
                else
                    app.h.BackgroundColor = 'white';
                end
                
                dydxSym = diff(f(sym('x')));
                g =@(x) eval(vectorize(char(dydxSym)));

                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.Mostrador)
                text(app.Mostrador,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                
                switch metodo
                    case 'Progressivas'
                        [x,y,dydx] = NDerivadaDFP(app,f,a,b,h); %aproximação
                        dydxExata = g(x); %exata
                        erroDFP=abs(dydxExata-dydx); %erro
                        mudaLamp(app, erroDFP);
                        
                        %plots
                        plot(app.Grafico,x,y,'-r')
                        hold(app.Grafico,'on')
                        plot(app.Grafico,x, dydxExata, '-b')
                        plot(app.Grafico,x,dydx,'-g')
                        
                        %legenda e tabela
                        legenda={'f(x)', "f'(x)", 'Progressiva'};
                        tabela = [x.',y.',dydxExata.', dydx.',erroDFP.'];
                        nomeColunas = {'x','y','Exata','Progressiva','Erro'};

                    case 'Regressivas'
                        [x,y,dydx] = NDerivadaDFR(app,f,a,b,h); %aproximação
                        dydxExata = g(x); %exata
                        erroDFR=abs(dydxExata-dydx); %erro
                        mudaLamp(app, erroDFR);
                        
                        %plots
                        plot(app.Grafico,x,y,'-r')
                        hold(app.Grafico,'on')
                        plot(app.Grafico,x, dydxExata, '-b')
                        plot(app.Grafico,x,dydx,'-g')
                        
                        %legenda e tabela
                        legenda={'f(x)', "f'(x)", 'Regressivas'};
                        tabela = [x.',y.',dydxExata.', dydx.',erroDFR.'];
                        nomeColunas = {'x','y','Exata','Regressiva','Erro'};

                    case '2 Derivada'
                        [x,y,dydx] = NDerivadaF2D(app,f,a,b,h); %aproximação
                        dydxExata = g(x); %exata
                        erroF2D=abs(dydxExata-dydx); %erro
                        mudaLamp(app, erroF2D);
                        
                        %plots
                        plot(app.Grafico,x,y,"-r")
                        hold(app.Grafico,'on')
                        plot(app.Grafico,x, dydxExata, '-b')
                        plot(app.Grafico,x,dydx,'-g')
                        
                        %legenda e tabela
                        legenda={'f(x)', "f'(x)", '2 Derivada'};
                        tabela = [x.',y.',dydxExata.', dydx.',erroF2D.'];
                        nomeColunas = {'x','y','Exata','2ª Derivada','Erro'};

                    case 'Progressivas 3 Pontos'
                        [x,y,dydx] = NDerivadaFPT(app,f,a,b,h); %aproximação
                        dydxExata = g(x); %exata
                        erroFPT=abs(dydxExata-dydx); %erro
                        mudaLamp(app, erroFPT);
                        
                        %plots
                        plot(app.Grafico,x,y,"-r")
                        hold(app.Grafico,'on')
                        plot(app.Grafico,x, dydxExata, '-b')
                        plot(app.Grafico,x,dydx,'-g')
                        
                        %legenda e tabela
                        legenda={'f(x)', "f'(x)", 'Progressiva 3 Pontos'};
                        tabela = [x.',y.',dydxExata.', dydx.',erroFPT.'];
                        nomeColunas = {'x','y','Exata','Progressiva 3 Pontos','Erro'};

                    case 'Regressivas 3 Pontos'
                        [x,y,dydx] = NDerivadaFRT(app,f,a,b,h); %aproximação
                        dydxExata = g(x); %exata
                        erroFRT=abs(dydxExata-dydx); %erro
                        mudaLamp(app, erroFRT);
                        
                        %plots
                        plot(app.Grafico,x,y,"-r")
                        hold(app.Grafico,'on')
                        plot(app.Grafico,x, dydxExata, '-b')
                        plot(app.Grafico,x,dydx,'-g')
                        
                        %legenda e tabela
                        legenda={'f(x)', "f'(x)", 'Regressiva 3 Pontos'};
                        tabela = [x.',y.',dydxExata.', dydx.',erroFRT.'];
                        nomeColunas = {'x','y','Exata','Regressiva 3 Pontos','Erro'};

                    case 'Centradas'
                        [x,y,dydx] = NDerivadaFC(app,f,a,b,h); %aproximação
                        dydxExata = g(x); %exata
                        erroFC=abs(dydxExata-dydx); %erro
                        mudaLamp(app, erroFC);
                        
                        %plots
                        plot(app.Grafico,x,y,"-r")
                        hold(app.Grafico,'on')
                        plot(app.Grafico,x, dydxExata, '-b')
                        plot(app.Grafico,x,dydx,'-g')
                        
                        %legenda e tabela
                        legenda={'f(x)', "f'(x)", 'Centrada'};
                        tabela = [x.',y.',dydxExata.', dydx.',erroFC.'];
                        nomeColunas = {'x','y','Exata','Centrada','Erro'};
                end
                
                legend(app.Grafico, legenda);
                hold(app.Grafico,"off")

                T = array2table(tabela);
                app.Tabela.Data = T; 
                app.Tabela.ColumnName = nomeColunas;

               %Resultado pelo Metodo de Simpsons e pelo Metodo dos Trapezios
                n = (b-a)/h;
                S = RSimpson(app,f,a,b,n);
                T = RTrapezios(app,f,a,b,n);
                app.Simp.Value = S;
                app.Trap.Value = T;

            catch ME
                errordlg(ME.message,"ERRO","modal")
            end
        end

        % Button pushed function: Atualizar2
        function Atualizar2ButtonPushed(app, event)
            strFxy = app.f2.Value;
            f =@(x,y) eval(vectorize(strFxy));
            a = str2num(app.a2.Value); 
            h1 = app.h2.Value; 
            b = str2num(app.b2.Value);
            c = str2num(app.c.Value); 
            h2 = app.h3.Value; 
            d = str2num(app.d.Value);
            
            erroFuncaoF=MException('MATLAB:appMaquinaCDI:badF','Introduza uma função z em y e x');
            erroA=MException('MATLAB:appMaquinaCDI:BadA','Introduza um número real em a');
            erroB=MException("MATLAB:bAtualizar:BadB", "Introduza um numero real maior que a");
            erroH=MException('MATLAB:appMaquinaCDI:badH','Introduza um numero inteiro maior que 0 em h');
            erroC=MException('MATLAB:appMaquinaCDI:badC','Introduza um número real em c');
            erroD=MException('MATLAB:appMaquinaCDI:badD','Introduza um número real em d maior que c');
            
            try
                try
                    erroFuncaoF=f(sym('x'),sym('y'));
                    app.f2.BackgroundColor = 'white';
                catch ME
                    app.f2.BackgroundColor = 'red';
                    throw(erroFuncaoF)
                end

                if ~(isscalar(a) && isreal(a))
                    app.a2.BackgroundColor = 'red';
                    throw(erroA)
                else
                    app.a2.BackgroundColor = 'white';
                end

                if ~(isscalar(b) && isreal(b) && b>a)
                    app.b2.BackgroundColor = 'red';
                    throw(erroB)
                else
                    app.b2.BackgroundColor = 'white';
                end

                if ~(isscalar(h1) && h1>0)
                    app.h2.BackgroundColor = 'red';
                    throw(erroH)
                else
                    app.h2.BackgroundColor = 'white';
                end
                
                if ~(isscalar(c) && isreal(d) && c<d)
                    app.c.BackgroundColor = 'red';
                    throw(erroC)
                else
                    app.c.BackgroundColor = 'white';
                end

                if ~(isscalar(d) && isreal(c) && d>c)
                    app.d.BackgroundColor = 'red';
                    throw(erroD)
                else
                    app.d.BackgroundColor = 'white';
                end

                if ~(isscalar(h2) && h2>0)
                    app.h3.BackgroundColor = 'red';
                    throw(erroH)
                else
                    app.h3.BackgroundColor = 'white';
                end

                [x,y] = meshgrid(a:h1:b, c:h2:d);
                z = f(x,y);
                mesh(app.Grafico2, x,y,z) %pode-se alterar para surf
                
                syms x y;
                dydx2Sym = diff(f,x,2);
                dydy2Sym = diff(f,y,2);
    
                % Mostrar 2 derivada em ordem a x
                if ~isempty(dydx2Sym)
                    formaLatex =['$','\frac{dz}{dx}=',latex(dydx2Sym),'$'];
                    cla(app.Mostrador2)
                    text(app.Mostrador2,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                end
    
                % Mostrar 2 derivada em ordem a y
                if ~isempty(dydy2Sym)
                    formaLatex =['$','\frac{dz}{dy}=',latex(dydy2Sym),'$'];
                    cla(app.Mostrador3)
                    text(app.Mostrador3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                end
    
                if isHarmonica(app,f)
                    strHarmonica = 'f é harmónica';
                    corHarmonica = 'g';
                else
                    strHarmonica = 'f não é harmónica';
                    corHarmonica = 'r';
                end
                app.Harmonica.Value = strHarmonica;
                app.Harmonica.FontColor = corHarmonica;
                
            catch Me
                errordlg(Me.message,'ERRO','modal');
            end
        end

        % Close request function: DerivaoeIntegracaoNumerica
        function DerivaoeIntegracaoNumericaCloseRequest(app, event)
            SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.DerivaoeIntegracaoNumerica);
            end
        end

        % Menu selected function: TabelaparaexcelMenu
        function TabelaparaexcelMenuSelected(app, event)
            try
                filename=append('data_',app.FormulasDNumerica.SelectedObject.Text,'.xlsx');

                writecell(app.Tabela.ColumnName',filename);
                writetable(app.Tabela.Data,filename,'WriteVariableNames', false,'Range','A2');
            catch
                waitfor(errordlg("Erro a exportar para o ficheiro","Erro"))
            end
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            open('Relatorio.pdf');
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            Autores();
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.f.Value = 'sin(x)';
            app.a.Value = '0';
            app.h.Value = 0.1;
            app.b.Value = '2*pi';
            app.DFP.Value = true;
            AtualizarButtonPushed(app);
            app.GrelhaGrafico.Value='Off';
            grid(app.Grafico,"off");
        end

        % Button pushed function: Reset2
        function Reset2ButtonPushed(app, event)
            app.f2.Value = 'x^2+y^2';
            app.a2.Value = '-2';
            app.h2.Value = 0.1;
            app.b2.Value = '2';
            app.c.Value = '-2';
            app.h3.Value = 0.1;
            app.d.Value = '2';
            app.GrelhaGrafico.Value='Off';
            grid(app.Grafico,"off");
            Atualizar2ButtonPushed(app);
        end

        % Value changed function: GrelhaGrafico
        function GrelhaGraficoValueChanged(app, event)
            value = strcmp(app.GrelhaGrafico.Value,'On');
            if(value)
                grid(app.Grafico,"on")
            else
                grid(app.Grafico,"off")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DerivaoeIntegracaoNumerica = uifigure('Visible', 'off');
            app.DerivaoeIntegracaoNumerica.Name = 'Derivação e Integração Numérica';
            app.DerivaoeIntegracaoNumerica.Icon = fullfile(pathToMLAPP, 'fotos_logos', 'logo.png');
            app.DerivaoeIntegracaoNumerica.CloseRequestFcn = createCallbackFcn(app, @DerivaoeIntegracaoNumericaCloseRequest, true);
            app.ExportarMenu = uimenu(app.DerivaoeIntegracaoNumerica);
            app.ExportarMenu.Text = 'Exportar';
            app.TabelaparaexcelMenu = uimenu(app.ExportarMenu);
            app.TabelaparaexcelMenu.MenuSelectedFcn = createCallbackFcn(app, @TabelaparaexcelMenuSelected, true);
            app.TabelaparaexcelMenu.Text = 'Tabela para excel';
            app.AboutMenu = uimenu(app.DerivaoeIntegracaoNumerica);
            app.AboutMenu.Text = 'About';
            app.RelatrioMenu = uimenu(app.AboutMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.AutoresMenu = uimenu(app.AboutMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.Tab = uitabgroup(app.DerivaoeIntegracaoNumerica);
            app.FrVr = uitab(app.Tab);
            app.FrVr.Title = 'Função real de variável real';
            app.DadosGeral = uipanel(app.FrVr);
            app.Mostrador = uiaxes(app.DadosGeral);
            app.Mostrador.XTick = [];
            app.Mostrador.YTick = [];
            app.Dados = uipanel(app.DadosGeral);
            app.Dados.Title = 'Dados';
            app.fxLabel = uilabel(app.Dados);
            app.fxLabel.Text = 'f(x) =';
            app.f = uieditfield(app.Dados, 'text');
            app.f.Value = 'sin(x)';
            app.hEditFieldLabel = uilabel(app.Dados);
            app.hEditFieldLabel.Text = 'h';
            app.h = uieditfield(app.Dados, 'numeric');
            app.h.Value = 0.1;
            app.aLabel = uilabel(app.Dados);
            app.aLabel.Text = 'a';
            app.a = uieditfield(app.Dados, 'text');
            app.a.Value = '0';
            app.aLabel_2 = uilabel(app.Dados);
            app.aLabel_2.Text = 'b';
            app.b = uieditfield(app.Dados, 'text');
            app.b.Value = '2*pi';
            app.RSimpsonsLabel = uilabel(app.DadosGeral);
            app.RSimpsonsLabel.Text = 'R. Simpson''s';
            app.Simp = uieditfield(app.DadosGeral, 'numeric');
            app.Simp.Editable = 'off';
            app.RTrapziosLabel = uilabel(app.DadosGeral);
            app.RTrapziosLabel.Text = 'R.Trapézios';
            app.Trap = uieditfield(app.DadosGeral, 'numeric');
            app.Trap.Editable = 'off';
            app.Atualizar = uibutton(app.DadosGeral, 'push');
            app.Atualizar.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButtonPushed, true);
            app.Atualizar.Text = 'Atualizar';
            app.FormulasDNumerica = uibuttongroup(app.DadosGeral);
            app.FormulasDNumerica.Title = 'Fórmulas de Diferenças Finitas';
            app.DFP = uiradiobutton(app.FormulasDNumerica);
            app.DFP.Text = 'Progressivas';
            app.DFP.Value = true;
            app.DFR = uiradiobutton(app.FormulasDNumerica);
            app.DFR.Text = 'Regressivas';
            app.DFC = uiradiobutton(app.FormulasDNumerica);
            app.DFC.Text = 'Centradas';
            app.DFP3 = uiradiobutton(app.FormulasDNumerica);
            app.DFP3.Text = 'Progressivas 3 Pontos';
            app.D2 = uiradiobutton(app.FormulasDNumerica);
            app.D2.Text = '2 Derivada';
            app.DFR3 = uiradiobutton(app.FormulasDNumerica);
            app.DFR3.Text = 'Regressivas 3 Pontos';
            app.Reset = uibutton(app.DadosGeral, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Reset';
            app.Resultados = uipanel(app.FrVr);
            app.Grafico = uiaxes(app.Resultados);
            title(app.Grafico, 'Gráfico')
            zlabel(app.Grafico, 'Z')
            app.Tabela = uitable(app.Resultados);
            app.Tabela.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Tabela.RowName = {};
            app.ErrodaaproximaoLabel = uilabel(app.Resultados);
            app.ErrodaaproximaoLabel.Text = 'Erro da aproximação';
            app.ErroAproximcao = uilamp(app.Resultados);
            app.GrelhadogrficoSwitchLabel = uilabel(app.Resultados);
            app.GrelhadogrficoSwitchLabel.Text = 'Grelha do gráfico';
            app.GrelhaGrafico = uiswitch(app.Resultados, 'slider');
            app.GrelhaGrafico.ValueChangedFcn = createCallbackFcn(app, @GrelhaGraficoValueChanged, true);
            app.Fr2Vr = uitab(app.Tab);
            app.Fr2Vr.Title = 'Função real de 2 variáveis reais';
            app.Dados2 = uipanel(app.Fr2Vr);
            app.Mostrador2 = uiaxes(app.Dados2);
            app.Mostrador2.XTick = [];
            app.Mostrador2.YTick = [];
            app.Mostrador3 = uiaxes(app.Dados2);
            app.Mostrador3.XTick = [];
            app.Mostrador3.YTick = [];
            app.DadosGeral2 = uipanel(app.Dados2);
            app.DadosGeral2.Title = 'Dados';
            app.fxyLabel = uilabel(app.DadosGeral2);
            app.fxyLabel.Text = 'f(x,y) = ';
            app.f2 = uieditfield(app.DadosGeral2, 'text');
            app.f2.Value = 'x^2+y^2';
            app.hLabel = uilabel(app.DadosGeral2);
            app.hLabel.Text = 'h';
            app.h2 = uieditfield(app.DadosGeral2, 'numeric');
            app.h2.Value = 0.1;
            app.hLabel_4 = uilabel(app.DadosGeral2);
            app.hLabel_4.Text = 'h3';
            app.h3 = uieditfield(app.DadosGeral2, 'numeric');
            app.h3.Value = 0.1;
            app.aLabel_3 = uilabel(app.DadosGeral2);
            app.aLabel_3.Text = 'a';
            app.a2 = uieditfield(app.DadosGeral2, 'text');
            app.a2.Value = '-2';
            app.aLabel_4 = uilabel(app.DadosGeral2);
            app.aLabel_4.Text = 'b';
            app.b2 = uieditfield(app.DadosGeral2, 'text');
            app.b2.Value = '2';
            app.aLabel_5 = uilabel(app.DadosGeral2);
            app.aLabel_5.Text = 'c';
            app.c = uieditfield(app.DadosGeral2, 'text');
            app.c.Value = '-2';
            app.aLabel_6 = uilabel(app.DadosGeral2);
            app.aLabel_6.Text = 'd';
            app.d = uieditfield(app.DadosGeral2, 'text');
            app.d.Value = '2';
            app.Atualizar2 = uibutton(app.Dados2, 'push');
            app.Atualizar2.ButtonPushedFcn = createCallbackFcn(app, @Atualizar2ButtonPushed, true);
            app.Atualizar2.Text = 'Atualizar';
            app.Harmonica = uieditfield(app.Dados2, 'text');
            app.Harmonica.Editable = 'off';
            app.DerivadaZX = uilabel(app.Dados2);
            app.DerivadaZX.Text = '2ª Derivada de Z em Ordem a X';
            app.DerivadaZY = uilabel(app.Dados2);
            app.DerivadaZY.Text = '2ª Derivada de Z em Ordem a Y';
            app.Reset2 = uibutton(app.Dados2, 'push');
            app.Reset2.ButtonPushedFcn = createCallbackFcn(app, @Reset2ButtonPushed, true);
            app.Reset2.Text = 'Reset';
            app.Resultados2 = uipanel(app.Fr2Vr);
            app.Grafico2 = uiaxes(app.Resultados2);
            xlabel(app.Grafico2, 'X')
            ylabel(app.Grafico2, 'Y')
            zlabel(app.Grafico2, 'Z')
            % Show the figure after all components are created
            app.DerivaoeIntegracaoNumerica.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DerivaoeIntegracaoNumerica)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DerivaoeIntegracaoNumerica)
        end
    end
end