classdef exemplo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MtodosNumricosSED             matlab.ui.Figure
        ExportarMenu                  matlab.ui.container.Menu
        TabelaParaExcel               matlab.ui.container.Menu
        About                         matlab.ui.container.Menu
        RelatorioMenu                 matlab.ui.container.Menu
        AutoresMenu                   matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Problema                      matlab.ui.control.DropDown
        ProblemaDropDownLabel         matlab.ui.control.Label
        Reset                         matlab.ui.control.Button
        Atualizar                     matlab.ui.control.Button
        GrelhaGrafico                 matlab.ui.control.Switch
        GrelhadogrficoSwitchLabel     matlab.ui.control.Label
        Metodos                       matlab.ui.container.ButtonGroup
        Todos                         matlab.ui.control.RadioButton
        RK2                           matlab.ui.control.RadioButton
        EulerM                        matlab.ui.control.RadioButton
        RK4                           matlab.ui.control.RadioButton
        Euler                         matlab.ui.control.RadioButton
        Dados                         matlab.ui.container.Panel
        v0                            matlab.ui.control.EditField
        v0Label                       matlab.ui.control.Label
        u0                            matlab.ui.control.EditField
        u0EditFieldLabel              matlab.ui.control.Label
        g                             matlab.ui.control.EditField
        ftyLabel_2                    matlab.ui.control.Label
        n                             matlab.ui.control.NumericEditField
        b                             matlab.ui.control.NumericEditField
        a                             matlab.ui.control.NumericEditField
        nEditFieldLabel               matlab.ui.control.Label
        bEditFieldLabel               matlab.ui.control.Label
        aEditFieldLabel               matlab.ui.control.Label
        f                             matlab.ui.control.EditField
        ftyLabel                      matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        ErroAproximcao                matlab.ui.control.Lamp
        VariaodoerrodaaproximaoLabel  matlab.ui.control.Label
        Tabela                        matlab.ui.control.Table
        Grafico                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        function changeEditOff(app)
            app.g.Editable="off";
            app.a.Editable="off";
            app.b.Editable="off";
            app.n.Editable="off";
            app.u0.Editable="off";
            app.v0.Editable="off";
        end
        
        function changeEditOn(app)
            app.g.Editable="on";
            app.a.Editable="on";
            app.b.Editable="on";
            app.n.Editable="on";
            app.u0.Editable="on";
            app.v0.Editable="on";
        end
        
        function mudaLamp(app, erro)
            if(abs(erro(length(erro))-erro(length(erro)-1))<0.1)
                app.ErroAproximcao.Color='green';
            else
                app.ErroAproximcao.Color='red';
            end
            
        end
        
        function mostraGraficoLinear(app, t, aprox)
            hold(app.Grafico,"on")
            plot(app.Grafico,t,aprox,"-g*")
            hold(app.Grafico,"off")
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            AtualizarButtonPushed(app, [])
        end

        % Button pushed function: Atualizar
        function AtualizarButtonPushed(app, event)
            %ler inputs
            syms u(t) v(t)

            strF = app.f.Value;
            app.f.Editable="off";
            strG = app.g.Value;
            f = @(t,u,v) eval(vectorize(strF));
            g = @(t,u,v) eval(vectorize(strG));        
            a=app.a.Value;
            b=app.b.Value;
            n=app.n.Value;
            u0=str2num(app.u0.Value);
            v0=str2num(app.v0.Value);
            app.ErroAproximcao.Color='white';

            %Mensagens de erro
            erroFuncaoG=MException("MATLAB:bAtualizar:BadF", "Introduza uma função em t, u e v");
            erroFuncaoF=MException("MATLAB:bAtualizar:BadF", "Introduza uma função em t, u e v");
            erroA=MException("MATLAB:bAtualizar:BadA", "Introduza um numero real em a");
            erroB=MException("MATLAB:bAtualizar:BadB", "Introduza um numero real maior que a");
            erroN=MException("MATLAB:bAtualizar:BadN", "Introduza um numero inteiro maior que 0 em n");
            erroU0=MException("MATLAB:bAtualizar:BadU0", "Introduza um numero real em u0");
            erroV0=MException("MATLAB:bAtualizar:BadV0", "Introduza um numero real em v0");

            try
                %falta verificar caso a função seja imaginária
                try %verificar g
                    z = g(sym('t'),u,v);
                    app.g.BackgroundColor = "w";  
                catch
                    app.g.BackgroundColor = [1,0,0]; % [r,g,b]
                    throw(erroFuncaoG)          
                end

                try %verificar f
                    z = f(sym('t'),u,v);
                    app.f.BackgroundColor = "w";  
                catch
                    app.f.BackgroundColor = [1,0,0]; % [r,g,b]
                    throw(erroFuncaoF)          
                end

                 if ~(isreal(a)) %verifica se a é real
                    app.a.BackgroundColor=[1,0,0]; % [r,g,b]
                    throw(erroA)   
                else
                     app.a.BackgroundColor=[1,1,1]; % [r,g,b]               
                end
                
                if ~(isreal(b) && b>a) %verifica se b é real e maior que a
                    app.b.BackgroundColor=[1,0,0]; % [r,g,b]
                    throw(erroB)   
                else
                     app.b.BackgroundColor=[1,1,1]; % [r,g,b]               
                end

                if ~(mod(n,1)==0 && n>=1) %verifica se n é inteiro e maior que zero
                    app.n.BackgroundColor=[1,0,0]; % [r,g,b]
                    throw(erroN);
                else
                    app.n.BackgroundColor=[1,1,1]; % [r,g,b]
                end

                if ~(isscalar(u0) && isreal(u0)) %verifica se u0 é real
                    app.u0.BackgroundColor=[1,0,0]; % [r,g,b]
                    throw(erroU0);
                else
                    app.u0.BackgroundColor=[1,1,1]; % [r,g,b]
                end

                 if ~(isscalar(v0) && isreal(v0)) %verifica se v0 é real
                    app.v0.BackgroundColor=[1,0,0]; % [r,g,b]
                    throw(erroV0);
                else
                    app.v0.BackgroundColor=[1,1,1]; % [r,g,b]
                 end

                valor=app.Metodos.SelectedObject.Text; 
                
                linear=0;
                sExataU=sExataSED(f,g,u0,v0);
                if(sExataU~=0)
                    linear=1;
                else
                    warndlg('A função introduzida não é linear!','AVISO','modal');
                end

                
                %Cálculo das aproximações
                [t,uEuler,~]=NEulerSED(f,g,a,b,n,u0,v0);
                [~,uEulerM,~]=NEulerMSED(f,g,a,b,n,u0,v0);
                [~,uRK2,~]=NRK2SED(f,g,a,b,n,u0,v0);
                [~,uRK4,~]=NRK4SED(f,g,a,b,n,u0,v0);
                
                
                if(linear)
                    h=@(t) eval(vectorize(char(sExataU)));
                    uExata=h(t); 
                
                    %Cálculo dos erros
                    erroEuler=abs(uExata-uEuler);
                    erroEulerM=abs(uExata-uEulerM);
                    erroRK2=abs(uExata-uRK2);
                    erroRK4=abs(uExata-uRK4);
                    
                    plot(app.Grafico,t,uExata,"-ro")
                else
                    %Cálculo dos erros em relação ao RK4
                    erroEulerNL=abs(uRK4-uEuler);
                    erroEulerMNL=abs(uRK4-uEulerM);
                    erroRK2NL=abs(uRK4-uRK2);
                end
                
                switch valor
                    case 'Euler'
                        app.Grafico.Title.String='Euler';                     

                        if(linear)
                            mostraGraficoLinear(app, t, uEuler);
                            mudaLamp(app, erroEuler);
                            legenda = {'Exata','Euler'};
                            tabela = [t.',uExata.',uEuler.',erroEuler.']; 
                            nomeColunas = {'t','Exata','Euler','erroEuler'};
                            hold(app.Grafico,"off")
                        else
                            plot(app.Grafico, t, uEuler,"-k*");
                            mudaLamp(app, erroEulerNL);
                            legenda = {'Euler'};
                            tabela = [t.',uEuler.', erroEulerNL.']; 
                            nomeColunas = {'t','Euler','erroEuler'};
                        end

                    case 'RK2'
                        app.Grafico.Title.String='RK2';

                        if(linear)
                            mostraGraficoLinear(app, t, uRK2);
                            mudaLamp(app, erroRK2);
                            legenda = {'Exata','RK2'};
                            tabela = [t.',uExata.',uRK2.',erroRK2.']; 
                            nomeColunas = {'t','Exata','RK2','erroRK2'};
                        else
                            plot(app.Grafico,t,uRK2,"-k*");
                            mudaLamp(app, erroRK2NL);
                            legenda = {'RK2'};
                            tabela = [t.',uRK2.',erroRK2NL.']; 
                            nomeColunas = {'t','RK2','erroRK2'};
                        end
    
                    case 'RK4'
                        app.Grafico.Title.String='RK4';

                        if(linear)
                            mostraGraficoLinear(app, t, uRK4);
                            mudaLamp(app, erroRK4);
                            legenda = {'Exata','RK4'};
                            tabela = [t.',uExata.',uRK4.',erroRK4.']; 
                            nomeColunas = {'t','Exata','RK4','erroRK4'};
                        else
                            plot(app.Grafico,t,uRK4,"-k*");
                            legenda = {'RK4'};
                            tabela = [t.',uRK4.']; 
                            nomeColunas = {'t','RK4'};
                        end
    
                    case 'EulerM'
                        app.Grafico.Title.String='EulerM';

                        if(linear)
                            mostraGraficoLinear(app, t, uEulerM);
                            mudaLamp(app, erroEulerM);
                            legenda = {'Exata','EulerM'};
                            tabela = [t.',uExata.',uEulerM.',erroEulerM.']; 
                            nomeColunas = {'t','Exata','EulerM','erroEulerM'};
                        else
                            plot(app.Grafico,t,uEulerM,"-k*");
                            mudaLamp(app, erroEulerMNL);
                            legenda = {'EulerM'};
                            tabela = [t.',uEulerM.',erroEulerMNL.']; 
                            nomeColunas = {'t','EulerM','erroEulerM'};
                        end

                    case 'Todos'
                        app.Grafico.Title.String='Todos';
                        plot(app.Grafico,t,uEuler,"-g*")
                        hold(app.Grafico,"on")
                        plot(app.Grafico,t,uEulerM,"-md")
                        plot(app.Grafico,t,uRK2,"-b+")
                        plot(app.Grafico,t,uRK4,"-kd")
                        

                        if(linear)     
                            legenda = {'Exata','Euler','EulerM','RK2','RK4'};
                            tabela = [t.',uExata.',uEuler.',uEulerM.',uRK2.',uRK4.',erroEuler.',erroEulerM.',erroRK2.',erroRK4.']; 
                            nomeColunas = {'t','Exata','Euler','EulerM','RK2','RK4','erroEuler','erroEulerM','erroRK2','erroRK4'};
                        else
                            legenda = {'Euler','EulerM','RK2','RK4'};
                            tabela = [t.',uEuler.',uEulerM.',uRK2.',uRK4.']; 
                            nomeColunas = {'t','Euler','EulerM','RK2','RK4'};
                        end          
                end

                legend(app.Grafico, legenda) 
                hold(app.Grafico,"off")
                
                T = array2table(tabela);
                app.Tabela.Data = T; 
                app.Tabela.ColumnName = nomeColunas;
            catch ME
                errordlg(ME.message,"ERRO","modal")
            end
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            open('Relatorio.pdf');
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            Autores();
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.f.Value='v';
            app.g.Value='-16*u';
            app.a.Value=0;
            app.b.Value=2;
            app.n.Value=10;
            app.u0.Value='2';
            app.v0.Value='2';
            app.Euler.Value=true;
            app.GrelhaGrafico.Value='Off';
            grid(app.Grafico,"off");
            changeEditOn(app);
            app.Problema.Value="Outro";
            AtualizarButtonPushed(app);
        end

        % Value changed function: GrelhaGrafico
        function GrelhaGraficoValueChanged(app, event)
            value = strcmp(app.GrelhaGrafico.Value,'On');
            if(value)
                grid(app.Grafico,"on")
            else
                grid(app.Grafico,"off")
            end
        end

        % Menu selected function: TabelaParaExcel
        function TabelaParaExcelMenuSelected(app, event)
            try
                filename=append('data_',app.Metodos.SelectedObject.Text,'.xlsx');

                writecell(app.Tabela.ColumnName',filename);
                writetable(app.Tabela.Data,filename,'WriteVariableNames', false,'Range','A2');
            catch
                waitfor(errordlg("Erro a exportar para o ficheiro","Erro"))
            end
        end

        % Callback function
        function SairMenuSelected(app, event)
            SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.MtodosNumricosSED);
            end
        end

        % Close request function: MtodosNumricosSED
        function MtodosNumricosSEDCloseRequest(app, event)
            SairMenuSelected(app, []);
        end

        % Callback function
        function PenduloMenuSelected(app, event)
    
        end

        % Callback function
        function MolamassasemamortecimentoMenuSelected(app, event)

        end

        % Value changed function: Problema
        function ProblemaValueChanged(app, event)
            value = app.Problema.Value;
            switch value
                case 'Pêndulo'
                    changeEditOff(app);
                    app.f.Value='v';
                    app.g.Value='-sin(u)-0.3*v';                                                         
                    app.a.Value=0;
                    app.b.Value=15;
                    app.n.Value=100;                                              
                    app.u0.Value='pi/2';
                    app.v0.Value='0';    
                    app.Euler.Value=true;

                case 'Mola-Massa sem Amortecimento'
                    changeEditOff(app);
                    app.f.Value='v';
                    app.g.Value='-16*u';
                    app.a.Value=0;
                    app.b.Value=4;
                    app.n.Value=100;
                    app.u0.Value='9';
                    app.v0.Value='0';
                    app.Euler.Value=true;

                case 'Mola-Massa com Amortecimento'
                    changeEditOff(app);
                    app.f.Value = 'v';
                    app.g.Value = '-10*v-25*u';
                    app.a.Value = 0;
                    app.b.Value = 2;
                    app.n.Value = 100;
                    app.u0.Value = '0';
                    app.v0.Value = '-4';
                    app.Euler.Value=true;

                case'Circuíto elétrico em série'
                    changeEditOff(app);
                    app.f.Value='v';
                    app.g.Value='-5*v-4*u+2*exp(-t/4)';
                    app.a.Value=0;
                    app.b.Value=10;
                    app.n.Value=100;
                    app.u0.Value='0';
                    app.v0.Value='0';
                    app.Euler.Value=true;

                case "Circuíto RCL"
                    changeEditOff(app);
                    app.f.Value = 'v';
                    app.g.Value = '-1/5*v-1/15*u';
                    app.a.Value = 0;
                    app.b.Value = 50;
                    app.n.Value = 100;
                    app.u0.Value = '0.03';
                    app.v0.Value = '0';
                    app.Euler.Value=true;

                case'Vibração mecânica'
                    changeEditOff(app);
                    app.f.Value = 'v';
                    app.g.Value = '-2*v-2*u+4*cos(t)+2*sin(t)';
                    app.a.Value = 0;
                    app.b.Value = 15;
                    app.n.Value = 100;
                    app.u0.Value = '0';
                    app.v0.Value = '3';
                    app.Euler.Value=true;

                case 'Outro'
                    changeEditOn(app);
                    ResetButtonPushed(app, []);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MtodosNumricosSED.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {606, 606};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {235, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MtodosNumricosSED = uifigure('Visible', 'off');
            app.MtodosNumricosSED.Name = 'Métodos Numéricos SED/PVI';
            app.MtodosNumricosSED.Icon = fullfile(pathToMLAPP, 'logo.png');
            app.MtodosNumricosSED.CloseRequestFcn = createCallbackFcn(app, @MtodosNumricosSEDCloseRequest, true);
            app.MtodosNumricosSED.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ExportarMenu = uimenu(app.MtodosNumricosSED);
            app.ExportarMenu.Text = 'Exportar';
            app.TabelaParaExcel = uimenu(app.ExportarMenu);
            app.TabelaParaExcel.MenuSelectedFcn = createCallbackFcn(app, @TabelaParaExcelMenuSelected, true);
            app.TabelaParaExcel.Text = 'Tabela para excel';
            app.About = uimenu(app.MtodosNumricosSED);
            app.About.Text = 'About';
            app.RelatorioMenu = uimenu(app.About);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatorio';
            app.AutoresMenu = uimenu(app.About);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.GridLayout = uigridlayout(app.MtodosNumricosSED);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Dados = uipanel(app.LeftPanel);
            app.Dados.Title = 'Dados';
            app.ftyLabel = uilabel(app.Dados);
            app.ftyLabel.Text = 'f(t,u,v) ';
            app.f = uieditfield(app.Dados, 'text');
            app.f.Value = 'v';
            app.aEditFieldLabel = uilabel(app.Dados);
            app.aEditFieldLabel.Text = 'a';
            app.bEditFieldLabel = uilabel(app.Dados);
            app.bEditFieldLabel.Text = 'b';
            app.nEditFieldLabel = uilabel(app.Dados);
            app.nEditFieldLabel.Text = 'n';
            app.a = uieditfield(app.Dados, 'numeric');
            app.b = uieditfield(app.Dados, 'numeric');
            app.b.Value = 2;
            app.n = uieditfield(app.Dados, 'numeric');
            app.n.Value = 10;
            app.ftyLabel_2 = uilabel(app.Dados);
            app.ftyLabel_2.Text = 'g(t,u,v) ';
            app.g = uieditfield(app.Dados, 'text');
            app.g.Value = '-16*u';
            app.u0EditFieldLabel = uilabel(app.Dados);
            app.u0EditFieldLabel.Text = 'u0';
            app.u0 = uieditfield(app.Dados, 'text');
            app.u0.Value = '2';
            app.v0Label = uilabel(app.Dados);
            app.v0Label.Text = 'v0';
            app.v0 = uieditfield(app.Dados, 'text');
            app.v0.Value = '2';
            app.Metodos = uibuttongroup(app.LeftPanel);
            app.Metodos.Title = 'Métodos';
            app.Euler = uiradiobutton(app.Metodos);
            app.Euler.Text = 'Euler';
            app.Euler.Value = true;
            app.RK4 = uiradiobutton(app.Metodos);
            app.RK4.Text = 'RK4';
            app.EulerM = uiradiobutton(app.Metodos);
            app.EulerM.Text = 'EulerM';
            app.RK2 = uiradiobutton(app.Metodos);
            app.RK2.Text = 'RK2';
            app.Todos = uiradiobutton(app.Metodos);
            app.Todos.Text = 'Todos';
            app.GrelhadogrficoSwitchLabel = uilabel(app.LeftPanel);
            app.GrelhadogrficoSwitchLabel.Text = 'Grelha do gráfico';
            app.GrelhaGrafico = uiswitch(app.LeftPanel, 'slider');
            app.GrelhaGrafico.ValueChangedFcn = createCallbackFcn(app, @GrelhaGraficoValueChanged, true);
            app.Atualizar = uibutton(app.LeftPanel, 'push');
            app.Atualizar.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButtonPushed, true);
            app.Atualizar.Text = 'Atualizar';
            app.Reset = uibutton(app.LeftPanel, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Reset';
            app.ProblemaDropDownLabel = uilabel(app.LeftPanel);
            app.ProblemaDropDownLabel.Text = 'Problema';
            app.Problema = uidropdown(app.LeftPanel);
            app.Problema.Items = {'Outro', 'Pêndulo', 'Mola-Massa com Amortecimento', 'Mola-Massa sem Amortecimento', 'Circuíto elétrico em série', 'Circuíto RCL', 'Vibração mecânica'};
            app.Problema.ValueChangedFcn = createCallbackFcn(app, @ProblemaValueChanged, true);
            app.Problema.Value = 'Outro';
            app.RightPanel = uipanel(app.GridLayout);
            app.Grafico = uiaxes(app.RightPanel);
            title(app.Grafico, 'Gráfico')
            xlabel(app.Grafico, 'X')
            ylabel(app.Grafico, 'Y')
            zlabel(app.Grafico, 'Z')
            app.Tabela = uitable(app.RightPanel);
            app.Tabela.ColumnName = {''};
            app.Tabela.RowName = {};
            app.VariaodoerrodaaproximaoLabel = uilabel(app.RightPanel);
            app.VariaodoerrodaaproximaoLabel.Text = 'Variação do erro da aproximação';
            app.ErroAproximcao = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.MtodosNumricosSED.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exemplo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MtodosNumricosSED)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MtodosNumricosSED)
        end
    end
end